classdef GUItry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MartiniButton          matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        EspressoMartiniButton  matlab.ui.control.Button
        ResumeButton           matlab.ui.control.Button
        RestartButton          matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end

     
    properties (Access = private)
        Running = true; % Logical to track if the script is running
        Paused = false; % Logical to track if the script is paused
        Timer % Timer object to control execution
        Timer2
        drinkType = 0;
    end
    
    methods (Access = private)
        
        function startScript(app)
            % % Initialize and run the main robot control script
            % app.Running = true;
            % app.Paused = false;
            % disp('Script started.');
            % 
            % % Call to the main function or initialize environment
            % % initializeEnvironment();  % Function to initialize the environment
            % makeDrink(app);          % Function for main script actions
            % Initialize and run the main robot control script
            app.Running = true;
            app.Paused = false;
            app.drinkType = 1;
            disp('Script started.');

            % Start a timer to control the execution
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) makeDrink(app));
            start(app.Timer);
        end
        function startScript2(app)
            % % Initialize and run the main robot control script
            % app.Running = true;
            % app.Paused = false;
            % disp('Script started.');
            % 
            % % Call to the main function or initialize environment
            % % initializeEnvironment();  % Function to initialize the environment
            % makeDrink(app);          % Function for main script actions
            % Initialize and run the main robot control script
            app.Running = true;
            app.Paused = false;
            disp('Script started.');
            app.drinkType = 2;

            % Start a timer to control the execution
            app.Timer2 = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) makeDrink2(app));
            start(app.Timer2);
        end
        
        function stopScript(app)
            % Stop the script execution
            app.Running = false;
            app.Paused = false;
            disp('Script stopped.');

            % Stop and delete the timer
            % if isvalid(app.Timer)
            %     stop(app.Timer);
            %     delete(app.Timer);
            % end

            % if isvalid(app.Timer2)
            %     stop(app.Timer2);
            %     delete(app.Timer2);
            % end

            % Force MATLAB to process events and stop the execution
            drawnow;
        end
        
        function resumeScript(app)
              % Resume the script if it was paused
            % if app.Paused
            %     app.Running = true;
            %     app.Paused = false;
            %     disp('Script resumed.');
            % end
            app.Running = true; % Set Running to true to resume execution
            disp('Script resumed.');
            
        end
        
        function restartScript(app)
            % % Restart the entire script
            % app.stopScript(); % Stop if running
            % pause(1); % Small delay to ensure reset
            % app.startScript(); % Start fresh
            disp('Script restarted.');
            if app.drinkType == 1
                makeDrink(app);
                % startScript(app);
            end
            if app.drinkType == 2
                makeDrink2(app)
                % startScript2(app);
            end
        end
        
        function makeDrink(app)
            %new for the sake of the estop
            app.Running = true;
            if ~app.Running
                return; % Exit if not running
            end
            
            % Main();
            
            while app.Running && ~app.Paused
                 % app.Running = true;
                % Main();
                %% Set Positions
                % adjustment varibale
                sx = -0.5; %variables for shaker position
                sy = -0.6;
                sz = 0.9;
                UR_OS1 = 0.3; %UR10 offset variable
                Jak_OS1 =1.0; %Jaka Zu offset

                steps_long=60;
                steps_short=30;
                v_short=20;

                
                %Environment Vairables
                tablePOS=[-0.4,0,0];
                Bottle1POS=[0, 0.8, 2];
                Bottle2POS=[-0.5, 0.8, 2];
                Bottle3POS=[-1,0.8,2];
                Button1POS = [-0.05, 0.8, 1.35];
                Button2POS = [-0.55, 0.8, 1.35];
                Button3POS = [-1.05, 0.8, 1.35];
                LightCurt1POS = [1.7, -1, 1.1];
                LightCurt2POS = [2.05, -0.4, 0];
                GlassPOS= [-2, 0, 1.1];
                ShakerPOS=[sx,sy,sz];
                % workspace = [-2.5 2.5 -2 2 -0.02 5];
                % scale =0.1;
                EStopPOS=[-2.15, -0.8, 1];
                FextPOS=[-2,-1.3,0.5];

                %Robot Pos
                % ShakerGrabPOS=[sx+0.2,sy,sz+0.1];
                ShakerGrabPOS=[sx,sy,sz];
                Mid1POS = [-0.30, 0.6, 1.35]; %positions between buttons
                Mid2POS = [-0.80, 0.6, 1.35];
                UR_default = [-1, 0, 1.35];
                StandPOS = [-0.55, 0, 1.35];
                HandoffPos = [0.16, 0 , 1.96];
                PourPos = [0.2, -2, 1.2];
                JakPos0 = [0.16,0,2.4];
                JakPos1 = [0.4,-0.22,2.43];
                JakPos2 = [-0.4,-0.22,2.43];


                %%  Plot environment.
                % [p1,s1,b1,b2,b3,b4,b5,b6,e1,f1] = env.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,EStopPOS,FextPOS);
                % [p1,s1,b1,b2,b3,b4,b5,b6,L1,L2,g1,e1,f1] = Environment.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,LightCurt1POS,LightCurt2POS,GlassPOS,EStopPOS,FextPOS);
                % drawnow();
                % hold on
                [p1,s1,b1,b2,b3,b4,b5,b6,L1,L2,g1,e1,f1] = Environment.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,LightCurt1POS,LightCurt2POS,GlassPOS,EStopPOS,FextPOS);
                drawnow();
                hold on


                %%  Plot Jaka Zu 3 Robot 2
                robot2 = JakaZu3(transl(0,0,3)*troty(pi));  % Initialize robot1 as Jaka Zu 3

                hold on;
                qC = robot2.model.getpos();
                base = robot2.model.fkineUTS(qC);
                baseTransform = base * trotz(pi/2);
                shakerHand = ShakerHand(baseTransform);
                %%  Plot LinearUR10 Robot 1
                robot1 = LinearUR10(transl(0,0,0.5));  % Initialize robot2 as LinearUR10
                hold on;

                qC = robot1.model.getpos();
                base = robot1.model.fkineUTS(qC);

                % Define a base transformation with a translation and rotation
                baseTransform = base * transl(0, 0, 0.1) * trotz(pi/2);

                % Apply transformations directly to both fingers
                finger = RG2Finger(baseTransform);                     % Transformation for finger1
                mfinger = RG2Finger(baseTransform * trotz(pi));         % Transformation for finger2, rotated 180° around z-axis


                %% Ikcon values to manipulate the arms position
                % toShakerAvoidTable = deg2rad([0 0 0 -90 0 90 0]);

                %% Move to Shaker
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1,ShakerGrabPOS,steps_long);%,toShakerAvoidTable);
                % Control.moveToPos(robot1,qTj);
                Control.moveToPos(robot1,qTj,finger,mfinger);

                %% Grab Shaker and move to default position
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                % Calculate move to Standing Position
                qTj = Control.CreateTrajectory(robot1, StandPOS,steps_long);
                %Delete the initial brick
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));


                %% Move to Drink/Button 1
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Button1POS,steps_long);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                % s1 = PlaceObject('ShakerBody.ply', transl(Button1Pos));
                %% Move to midpoint1
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Mid1POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Button2
                % if ~app.Running
                % break; % Exit if not running
                % end
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Button2POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Midpoint2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Mid2POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Button 3
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Button3POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move back to midpoint
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, UR_default,steps_long);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Handoff
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, HandoffPos,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));

                %% Move to Handoff R2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos0,steps_short);
                Control.moveToPosShaking(robot2,qTj,shakerHand);
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos1,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos2,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos1,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos2,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Handoff R2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos0,steps_short);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));

                %% Move to glass
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, GlassPOS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                drawnow;

                pause(0.5); % Adjust as needed for performance
            end
        end

        function makeDrink2(app)
            %new for the sake of the estop
            app.Running = true;
            if ~app.Running
                return; % Exit if not running
            end
            
            % Main();
            
            while app.Running && ~app.Paused
                 % app.Running = true;
                % Main();
                %% Set Positions
                % adjustment varibale
                sx = -0.5; %variables for shaker position
                sy = -0.6;
                sz = 0.9;
                UR_OS1 = 0.3; %UR10 offset variable
                Jak_OS1 =1.0; %Jaka Zu offset

                steps_long=60;
                steps_short=30;
                v_short=20;

                
                %Environment Vairables
                tablePOS=[-0.4,0,0];
                Bottle1POS=[0, 0.8, 2];
                Bottle2POS=[-0.5, 0.8, 2];
                Bottle3POS=[-1,0.8,2];

                Button2POS = [-0.55, 0.8, 1.35];
                Button3POS = [-1.05, 0.8, 1.35];
                LightCurt1POS = [1.7, -1, 1.1];
                LightCurt2POS = [2.05, -0.4, 0];
                GlassPOS= [-2, 0, 1.1];
                ShakerPOS=[sx,sy,sz];
                % workspace = [-2.5 2.5 -2 2 -0.02 5];
                % scale =0.1;
                EStopPOS=[-2.15, -0.8, 1];
                FextPOS=[-2,-1.3,0.5];

                %Robot Pos
                % ShakerGrabPOS=[sx+0.2,sy,sz+0.1];
                ShakerGrabPOS=[sx,sy,sz];
                Mid1POS = [-0.30, 0.6, 1.35]; %positions between buttons
                Mid2POS = [-0.80, 0.6, 1.35];
                UR_default = [-1, 0, 1.35];
                StandPOS = [-0.55, 0, 1.35];
                HandoffPos = [0.16, 0 , 1.96];
                PourPos = [0.2, -2, 1.2];
                JakPos0 = [0.16,0,2.4];
                JakPos1 = [0.4,-0.22,2.43];
                JakPos2 = [-0.4,-0.22,2.43];


                %%  Plot environment.
                % [p1,s1,b1,b2,b3,b4,b5,b6,e1,f1] = env.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,EStopPOS,FextPOS);
                % [p1,s1,b1,b2,b3,b4,b5,b6,L1,L2,g1,e1,f1] = Environment.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,LightCurt1POS,LightCurt2POS,GlassPOS,EStopPOS,FextPOS);
                % drawnow();
                % hold on
                [p1,s1,b1,b2,b3,b4,b5,b6,L1,L2,g1,e1,f1] = Environment.simEnvironment(tablePOS,ShakerPOS,Bottle1POS,Bottle2POS,Bottle3POS,Button1POS,Button2POS,Button3POS,LightCurt1POS,LightCurt2POS,GlassPOS,EStopPOS,FextPOS);
                drawnow();
                hold on


                %%  Plot Jaka Zu 3 Robot 2
                robot2 = JakaZu3(transl(0,0,3)*troty(pi));  % Initialize robot1 as Jaka Zu 3

                hold on;
                qC = robot2.model.getpos();
                base = robot2.model.fkineUTS(qC);
                baseTransform = base * trotz(pi/2);
                shakerHand = ShakerHand(baseTransform);
                %%  Plot LinearUR10 Robot 1
                robot1 = LinearUR10(transl(0,0,0.5));  % Initialize robot2 as LinearUR10
                hold on;

                qC = robot1.model.getpos();
                base = robot1.model.fkineUTS(qC);

                % Define a base transformation with a translation and rotation
                baseTransform = base * transl(0, 0, 0.1) * trotz(pi/2);

                % Apply transformations directly to both fingers
                finger = RG2Finger(baseTransform);                     % Transformation for finger1
                mfinger = RG2Finger(baseTransform * trotz(pi));         % Transformation for finger2, rotated 180° around z-axis


                %% Ikcon values to manipulate the arms position
                % toShakerAvoidTable = deg2rad([0 0 0 -90 0 90 0]);

                %% Move to Shaker
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1,ShakerGrabPOS,steps_long);%,toShakerAvoidTable);
                % Control.moveToPos(robot1,qTj);
                Control.moveToPos(robot1,qTj,finger,mfinger);

                %% Grab Shaker and move to default position
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                % Calculate move to Standing Position
                qTj = Control.CreateTrajectory(robot1, StandPOS,steps_long);
                %Delete the initial brick
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Button2
                
                % if ~app.Running
                % break; % Exit if not running
                % end
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Button2POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Midpoint2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Mid2POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Button 3
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, Button3POS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move back to midpoint
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, UR_default,steps_long);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Handoff
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, HandoffPos,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));

                %% Move to Handoff R2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos0,steps_short);
                Control.moveToPosShaking(robot2,qTj,shakerHand);
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos1,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos2,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos1,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move shaker Robot 2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos2,steps_short);

                % Control.moveToPos(robot1,qTj);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                %% Move to Handoff R2
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectoryShaking(robot2, JakPos0,steps_short);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShakerShaking(robot2,qTj,shakerHand);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));

                %% Move to glass
                if ~app.Running
                    % Wait until Running is true
                    while ~app.Running
                        pause(0.1); % Wait briefly to avoid busy-waiting
                    end
                end
                qTj = Control.CreateTrajectory(robot1, GlassPOS,steps_short);
                %Control.moveToPos(robot1,qTj,finger,mfinger);
                try delete(s1);
                catch ME
                end
                s1NewPos = Control.PlotShaker(robot1,qTj,finger,mfinger);
                s1 = PlaceObject('ShakerBody.ply', transl(s1NewPos));
                drawnow;

                pause(0.5); % Adjust as needed for performance
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RestartButton
        function RestartCodeButtonPushed(app, event)
            app.restartScript();
        end

        % Button pushed function: ResumeButton
        function ResumeCodeButtonPushed(app, event)
            app.resumeScript();
        end

        % Button pushed function: Button
        function StopCodeButtonPushed(app, event)
            app.stopScript();
        end

        % Button pushed function: EspressoMartiniButton
        function MakeEMartiniButtonPushed(app, event)
            app.startScript();
        end

        % Button pushed function: MartiniButton
        function MakeMartiniButtonPushed(app, event)
            app.startScript2();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'guibg.jpg');
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartCodeButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeCodeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.EspressoMartiniButton = uibutton(app.UIFigure, 'push');
            app.EspressoMartiniButton.ButtonPushedFcn = createCallbackFcn(app, @MakeEMartiniButtonPushed, true);
            app.EspressoMartiniButton.Text = 'Espresso Martini';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StopCodeButtonPushed, true);
            app.Button.Icon = 'e-stop.jpg';
            app.Button.Text = '';
            app.MartiniButton = uibutton(app.UIFigure, 'push');
            app.MartiniButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMartiniButtonPushed, true);
            app.MartiniButton.Text = 'Martini';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end